classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WordtoPDFConvertarLabel    matlab.ui.control.Label
        BrowseButton               matlab.ui.control.Button
        BrowseButton_2             matlab.ui.control.Button
        ImportDocEditField_2Label  matlab.ui.control.Label
        ImportDocEditField_2       matlab.ui.control.EditField
        ExportPDFEditFieldLabel    matlab.ui.control.Label
        ExportPDFEditField         matlab.ui.control.EditField
        ConversionStatusLampLabel  matlab.ui.control.Label
        ConversionStatusLamp       matlab.ui.control.Lamp
        STARTButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.ConversionStatusLamp.Color =[0.15,0.15,0.15];
            [filename,pathname] = uigetfile({'*.doc'}, 'Fill Selector');
            app.ImportDocEditField_2.Value = strcat(pathname,filename);
            app.UIFigure.Visible ='off';
            app.UIFigure.Visible ='on';   
        end

        % Value changed function: ImportDocEditField_2
        function ImportDocEditField_2ValueChanged(app, event)

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.ConversionStatusLamp.Color =[0.15,0.15,0.15];
            [filename,pathname] = uiputfile('Result.pdf');
            app.ExportPDFEditField.Value = strcat(pathname,filename);
            app.UIFigure.Visible ='off';
            app.UIFigure.Visible ='on';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
         app.STARTButton.Enable = 'off';
         app.ConversionStatusLamp.Color = [1,0,0];
         docFile = app.ImportDocEditField_2.Value;
         pdfFile = app.ExportPDFEditField.Value;
         [status,message] =doc2pdf(docFile,pdfFile);
         if status==1
           app.ConversionStatusLamp.Color = [0,1,0];
         end
       app.STARTButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WordtoPDFConvertarLabel = uilabel(app.UIFigure);
            app.WordtoPDFConvertarLabel.Text = 'Word  to Pdf';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ImportDocEditField_2Label = uilabel(app.UIFigure);
            app.ImportDocEditField_2Label.Text = 'Import Doc';
            app.ImportDocEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ImportDocEditField_2.ValueChangedFcn = createCallbackFcn(app, @ImportDocEditField_2ValueChanged, true);
            app.ExportPDFEditFieldLabel = uilabel(app.UIFigure);
            app.ExportPDFEditFieldLabel.Text = 'Export PDF';
            app.ExportPDFEditField = uieditfield(app.UIFigure, 'text');
            app.ConversionStatusLampLabel = uilabel(app.UIFigure);
            app.ConversionStatusLampLabel.Text = 'Conversion Status';
            app.ConversionStatusLamp = uilamp(app.UIFigure);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end